classdef SLIP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        vbelt1SliderLabel       matlab.ui.control.Label
        vbelt1Slider            matlab.ui.control.Slider
        vbelt2SliderLabel       matlab.ui.control.Label
        vbelt2Slider            matlab.ui.control.Slider
        theta01SliderLabel      matlab.ui.control.Label
        theta01Slider           matlab.ui.control.Slider
        theta02SliderLabel      matlab.ui.control.Label
        theta02Slider           matlab.ui.control.Slider
        U01SliderLabel          matlab.ui.control.Label
        U01Slider               matlab.ui.control.Slider
        h01Label                matlab.ui.control.Label
        h01Slider               matlab.ui.control.Slider
        cSliderLabel            matlab.ui.control.Label
        cSlider                 matlab.ui.control.Slider
        vbelt1Spinner           matlab.ui.control.Spinner
        vbelt2Spinner           matlab.ui.control.Spinner
        theta01Spinner          matlab.ui.control.Spinner
        theta02Spinner          matlab.ui.control.Spinner
        U01Spinner              matlab.ui.control.Spinner
        h01Spinner              matlab.ui.control.Spinner
        cSpinner                matlab.ui.control.Spinner
        K1SliderLabel           matlab.ui.control.Label
        K1Slider                matlab.ui.control.Slider
        K2SliderLabel           matlab.ui.control.Label
        K2Slider                matlab.ui.control.Slider
        K1Spinner               matlab.ui.control.Spinner
        K2Spinner               matlab.ui.control.Spinner
        K1K2Label               matlab.ui.control.Label
        sameKSwitch             matlab.ui.control.ToggleSwitch
        EXPORTButton            matlab.ui.control.Button
        filenameEditFieldLabel  matlab.ui.control.Label
        filenameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        lastUsedK % Records the last used K (stiffness) slider for use in the sameKSwitch
    end
    
    methods (Access = private)
        
        function [K1,K2] = solveAndPlotSLIP(app)
            %Solve
            app.UIAxes.cla;
            vbelt1 = app.vbelt1Slider.Value;
            vbelt2 = app.vbelt2Slider.Value;
            theta01 = app.theta01Slider.Value;
            theta02 = app.theta02Slider.Value;
            U01 = app.U01Slider.Value;
            h01 = app.h01Slider.Value;
            c = app.cSlider.Value;
            [K1,K2] = checkKSwitch(app);
            [ti,xi,yi,ui,vi,~,~,T1,T2,t2flight,d,Ef,E0,xprev,yprev] = SLIPsim(vbelt1,vbelt2,theta01,theta02,U01,h01,c,K1,K2);
            
            %Plot
            hold(app.UIAxes,'on')
            n = 120;
            for i = 1:n+1
                lwidth = 1;
                if ti(i) <= T1(end)
                    footx = 0 - vbelt1*ti(i);
                    lcolor = [ti(i)/T1(end) 0 0];
                    lh(2) = plot(app.UIAxes,[footx xi(i)],[0 yi(i)],'Color',lcolor,'LineWidth',lwidth);
                elseif ti(i) >= T2(1) && ti(i) <=t2flight(1)
                    footx = d - vbelt2*(ti(i) - T2(1));
                    lcolor = [0 0 (ti(i)-T2(1))/(T2(end)-T2(1))];
                    lh(2) = plot(app.UIAxes,[footx xi(i)],[0 yi(i)],'Color',lcolor,'LineWidth',lwidth);
                end
                lh(1) = plot(app.UIAxes,xi(i),yi(i),'o','markersize',8,'markerfacecolor',lcolor,'markeredgecolor',lcolor);
            end
            plot(app.UIAxes,xi,yi,'y--','LineWidth',2);
            plot(app.UIAxes,xprev,yprev,'k--','LineWidth',2); %path falling into Phase 1
            %Display
            discrep = [xi(end)-xi(1) yi(end)-yi(1) ui(end)-ui(1) vi(end)-vi(1)];
            text(app.UIAxes,0.05,0.95,['Energy gained: ',num2str(Ef-E0)],'units','normalized');
            text(app.UIAxes,0.05,0.90,['\deltax = ',num2str(discrep(1))],'units','normalized');
            text(app.UIAxes,0.05,0.85,['\deltay = ',num2str(discrep(2))],'units','normalized');
            text(app.UIAxes,0.05,0.80,['\deltau = ',num2str(discrep(3))],'units','normalized');
            text(app.UIAxes,0.05,0.75,['\deltav = ',num2str(discrep(4))],'units','normalized');
        end
    
        function [K1out,K2out] = checkKSwitch(app)
            K1in = app.K1Slider.Value;
            K2in = app.K2Slider.Value;
            K1out = K1in;
            K2out = K2in;
            if strcmp(app.sameKSwitch.Value,'On')
                switch app.lastUsedK
                    case 1
                        K2out = K1in;
                    case 2
                        K1out = K2in;
                end
            end
        end
    
    end


    methods (Access = private)

        % Value changed function: vbelt1Slider
        function vbelt1SliderValueChanged(app, event)
            app.vbelt1Spinner.Value = app.vbelt1Slider.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: vbelt2Slider
        function vbelt2SliderValueChanged(app, event)
            app.vbelt2Spinner.Value = app.vbelt2Slider.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: theta01Slider
        function theta01SliderValueChanged(app, event)
            app.theta01Spinner.Value = app.theta01Slider.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: theta02Slider
        function theta02SliderValueChanged(app, event)
            app.theta02Spinner.Value = app.theta02Slider.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: U01Slider
        function U01SliderValueChanged(app, event)
            app.U01Spinner.Value = app.U01Slider.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: h01Slider
        function h01SliderValueChanged(app, event)
            app.h01Spinner.Value = app.h01Slider.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: cSlider
        function cSliderValueChanged(app, event)
            app.cSpinner.Value = app.cSlider.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: vbelt1Spinner
        function vbelt1SpinnerValueChanged(app, event)
            app.vbelt1Slider.Value = app.vbelt1Spinner.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: vbelt2Spinner
        function vbelt2SpinnerValueChanged(app, event)
            app.vbelt2Slider.Value = app.vbelt2Spinner.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: theta01Spinner
        function theta01SpinnerValueChanged(app, event)
            app.theta01Slider.Value = app.theta01Spinner.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: theta02Spinner
        function theta02SpinnerValueChanged(app, event)
            app.theta02Slider.Value = app.theta02Spinner.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: U01Spinner
        function U01SpinnerValueChanged(app, event)
            app.U01Slider.Value = app.U01Spinner.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: h01Spinner
        function h01SpinnerValueChanged(app, event)
            app.h01Slider.Value = app.h01Spinner.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: cSpinner
        function cSpinnerValueChanged(app, event)
            app.cSlider.Value = app.cSpinner.Value;
            solveAndPlotSLIP(app);
            
        end

        % Value changed function: K1Slider
        function K1SliderValueChanged(app, event)
            app.lastUsedK = 1;
            app.K1Spinner.Value = app.K1Slider.Value;
            [K1,K2] = solveAndPlotSLIP(app);
            app.K1Slider.Value = K1;
            app.K1Spinner.Value = K1;
            app.K2Slider.Value = K2;
            app.K2Spinner.Value = K2;
            
        end

        % Value changed function: K2Slider
        function K2SliderValueChanged(app, event)
            app.lastUsedK = 2;
            app.K2Spinner.Value = app.K2Slider.Value;
            [K1,K2] = solveAndPlotSLIP(app);
            app.K1Slider.Value = K1;
            app.K1Spinner.Value = K1;
            app.K2Slider.Value = K2;
            app.K2Spinner.Value = K2;
            
        end

        % Value changed function: K1Spinner
        function K1SpinnerValueChanged(app, event)
            app.lastUsedK = 1;
            app.K1Slider.Value = app.K1Spinner.Value;
            [K1,K2] = solveAndPlotSLIP(app);
            app.K1Slider.Value = K1;
            app.K1Spinner.Value = K1;
            app.K2Slider.Value = K2;
            app.K2Spinner.Value = K2;
            
        end

        % Value changed function: K2Spinner
        function K2SpinnerValueChanged(app, event)
            app.lastUsedK = 2;
            app.K2Slider.Value = app.K2Spinner.Value;
            [K1,K2] = solveAndPlotSLIP(app);
            app.K1Slider.Value = K1;
            app.K1Spinner.Value = K1;
            app.K2Slider.Value = K2;
            app.K2Spinner.Value = K2;
            
        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            vbelt1 = app.vbelt1Slider.Value;
            vbelt2 = app.vbelt2Slider.Value;
            theta01 = app.theta01Slider.Value;
            theta02 = app.theta02Slider.Value;
            U01 = app.U01Slider.Value;
            h01 = app.h01Slider.Value;
            c = app.cSlider.Value;
            K1 = app.K1Slider.Value;
            K2 = app.K2Slider.Value;
            [~,xi,yi,ui,vi,~,~,~,~,~,~,Ef,E0,~,~] = SLIPsim(vbelt1,vbelt2,theta01,theta02,U01,h01,c,K1,K2);
            
            %Display
            Egain = Ef-E0;
            discrep = [xi(end)-xi(1) yi(end)-xi(1) yi(end)-xi(1) ui(end)-ui(1) vi(end)-vi(1)];
            
            filename = app.filenameEditField.Value;
            if isempty(filename)
                app.EXPORTButton.Tooltip = 'Please enter a file name';
            else
                save(['C:\Users\BertramLab\Documents\MATLAB\Quang\SLIP-SBT-ode45\',filename]);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SLIP GUI')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.vbelt1SliderLabel = uilabel(app.UIFigure);
            app.vbelt1SliderLabel.Text = 'vbelt1';
            app.vbelt1Slider = uislider(app.UIFigure);
            app.vbelt1Slider.Limits = [0 10];
            app.vbelt1Slider.ValueChangedFcn = createCallbackFcn(app, @vbelt1SliderValueChanged, true);
            app.vbelt2SliderLabel = uilabel(app.UIFigure);
            app.vbelt2SliderLabel.Text = 'vbelt2';
            app.vbelt2Slider = uislider(app.UIFigure);
            app.vbelt2Slider.Limits = [0 10];
            app.vbelt2Slider.ValueChangedFcn = createCallbackFcn(app, @vbelt2SliderValueChanged, true);
            app.theta01SliderLabel = uilabel(app.UIFigure);
            app.theta01SliderLabel.Text = 'theta01';
            app.theta01Slider = uislider(app.UIFigure);
            app.theta01Slider.Limits = [-1.5707963267949 1.5707963267949];
            app.theta01Slider.ValueChangedFcn = createCallbackFcn(app, @theta01SliderValueChanged, true);
            app.theta02SliderLabel = uilabel(app.UIFigure);
            app.theta02SliderLabel.Text = 'theta02';
            app.theta02Slider = uislider(app.UIFigure);
            app.theta02Slider.Limits = [-1.5707963267949 1.5707963267949];
            app.theta02Slider.ValueChangedFcn = createCallbackFcn(app, @theta02SliderValueChanged, true);
            app.U01SliderLabel = uilabel(app.UIFigure);
            app.U01SliderLabel.Text = 'U01';
            app.U01Slider = uislider(app.UIFigure);
            app.U01Slider.Limits = [0 10];
            app.U01Slider.ValueChangedFcn = createCallbackFcn(app, @U01SliderValueChanged, true);
            app.h01Label = uilabel(app.UIFigure);
            app.h01Label.Text = 'h01';
            app.h01Slider = uislider(app.UIFigure);
            app.h01Slider.Limits = [10 20];
            app.h01Slider.ValueChangedFcn = createCallbackFcn(app, @h01SliderValueChanged, true);
            app.h01Slider.Value = 12;
            app.cSliderLabel = uilabel(app.UIFigure);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.UIFigure);
            app.cSlider.Limits = [0 1];
            app.cSlider.ValueChangedFcn = createCallbackFcn(app, @cSliderValueChanged, true);
            app.vbelt1Spinner = uispinner(app.UIFigure);
            app.vbelt1Spinner.ValueChangedFcn = createCallbackFcn(app, @vbelt1SpinnerValueChanged, true);
            app.vbelt2Spinner = uispinner(app.UIFigure);
            app.vbelt2Spinner.ValueChangedFcn = createCallbackFcn(app, @vbelt2SpinnerValueChanged, true);
            app.theta01Spinner = uispinner(app.UIFigure);
            app.theta01Spinner.Step = 0.001;
            app.theta01Spinner.Limits = [-1.5707963267949 1.5707963267949];
            app.theta01Spinner.ValueChangedFcn = createCallbackFcn(app, @theta01SpinnerValueChanged, true);
            app.theta02Spinner = uispinner(app.UIFigure);
            app.theta02Spinner.Step = 0.001;
            app.theta02Spinner.Limits = [-1.5707963267949 1.5707963267949];
            app.theta02Spinner.ValueChangedFcn = createCallbackFcn(app, @theta02SpinnerValueChanged, true);
            app.U01Spinner = uispinner(app.UIFigure);
            app.U01Spinner.Step = 0.5;
            app.U01Spinner.Limits = [0 10];
            app.U01Spinner.ValueChangedFcn = createCallbackFcn(app, @U01SpinnerValueChanged, true);
            app.h01Spinner = uispinner(app.UIFigure);
            app.h01Spinner.Step = 0.5;
            app.h01Spinner.Limits = [10 20];
            app.h01Spinner.ValueChangedFcn = createCallbackFcn(app, @h01SpinnerValueChanged, true);
            app.h01Spinner.Value = 10;
            app.cSpinner = uispinner(app.UIFigure);
            app.cSpinner.Step = 0.01;
            app.cSpinner.Limits = [0 1];
            app.cSpinner.ValueChangedFcn = createCallbackFcn(app, @cSpinnerValueChanged, true);
            app.K1SliderLabel = uilabel(app.UIFigure);
            app.K1SliderLabel.Text = 'K1';
            app.K1Slider = uislider(app.UIFigure);
            app.K1Slider.Limits = [0 20];
            app.K1Slider.ValueChangedFcn = createCallbackFcn(app, @K1SliderValueChanged, true);
            app.K1Slider.Value = 1;
            app.K2SliderLabel = uilabel(app.UIFigure);
            app.K2SliderLabel.Text = 'K2';
            app.K2Slider = uislider(app.UIFigure);
            app.K2Slider.Limits = [0 20];
            app.K2Slider.ValueChangedFcn = createCallbackFcn(app, @K2SliderValueChanged, true);
            app.K2Slider.Value = 1;
            app.K1Spinner = uispinner(app.UIFigure);
            app.K1Spinner.Step = 0.5;
            app.K1Spinner.Limits = [0 20];
            app.K1Spinner.ValueChangedFcn = createCallbackFcn(app, @K1SpinnerValueChanged, true);
            app.K1Spinner.Value = 1;
            app.K2Spinner = uispinner(app.UIFigure);
            app.K2Spinner.Step = 0.5;
            app.K2Spinner.Limits = [0 20];
            app.K2Spinner.ValueChangedFcn = createCallbackFcn(app, @K2SpinnerValueChanged, true);
            app.K2Spinner.Value = 1;
            app.K1K2Label = uilabel(app.UIFigure);
            app.K1K2Label.Text = 'K1 = K2';
            app.sameKSwitch = uiswitch(app.UIFigure, 'toggle');
            app.EXPORTButton = uibutton(app.UIFigure, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = SLIP_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end